classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReceiverAppUIFigure  matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        current_time;
        svb;
        bvb;
        wn;
        qbn;
        PosN;
        VelN;
        HWhl
        m;
        TcmdWhl;
    end
 
    methods (Access = private)

        function [plot_w1_measured,plot_w2_measured,plot_w3_measured] = initializeAxes(app)
%             Axes_Body   = [1 0 0; 0 1 0; 0 0 1];
%             plot_U1     = plot3(app.UIAxes,[0,Axes_Body(1,1)],[0,Axes_Body(1,2)],...
%                 [0,Axes_Body(1,3)],'-b','Linewidth',2,'Tag','plot_U1');
%             plot_U2     = plot3(app.UIAxes,[0,Axes_Body(2,1)],[0,Axes_Body(2,2)],...
%                 [0,Axes_Body(2,3)],'-g','Linewidth',2,'Tag','plot_U2');
%             plot_U3     = plot3(app.UIAxes,[0,Axes_Body(3,1)],[0,Axes_Body(3,2)],...
%                 [0,Axes_Body(3,3)],'-r','Linewidth',2,'Tag','plot_U3');
%             plot3(app.UIAxes,[0,Axes_Body(1,1)],[0,Axes_Body(1,2)],...
%                 [0,Axes_Body(1,3)],'-k','Linewidth',2);
%             plot3(app.UIAxes,[0,Axes_Body(2,1)],[0,Axes_Body(2,2)],...
%                 [0,Axes_Body(2,3)],'-k','Linewidth',2);
%             plot3(app.UIAxes,[0,Axes_Body(3,1)],[0,Axes_Body(3,2)],...
%                 [0,Axes_Body(3,3)],'-k','Linewidth',2);
            
            plot_w1_measured = plot(app.UIAxes,0,0,'-b','Linewidth',1,'Tag','plot_w1_measured');
            plot_w2_measured = plot(app.UIAxes,0,0,'-g','Linewidth',1,'Tag','plot_w2_measured');
            plot_w3_measured = plot(app.UIAxes,0,0,'-r','Linewidth',1,'Tag','plot_w3_measured');
%             title("Angular velocity (rad/s)");
        end
        
        function [current_time, svb, bvb, wn, qbn, HWhl, PosN, VelN]=read_data(app, client)
            data = readline(client);
            dateString = sscanf(data,"TIME %d-%d-%d:%d:%f");
            current_time = datetime(dateString(1),1,1,dateString(3),dateString(4),dateString(5))+dateString(2)-1;
            (readline(client));
            (readline(client));            
            data = readline(client);
            svb=sscanf(data,"SC[0].svb = %e %e %e");
            data = readline(client);
            bvb = sscanf(data,"SC[0].bvb = %e %e %e");
            (readline(client));
            (readline(client));
            (readline(client));
            data = readline(client);
            HWhl0 = sscanf(data,"SC[0].AC.Whl[0].H = %e");
            data = readline(client);
            HWhl1 = sscanf(data,"SC[0].AC.Whl[1].H = %e");
            data = readline(client);
            HWhl2 = sscanf(data,"SC[0].AC.Whl[2].H = %e");
            HWhl = [HWhl0, HWhl1, HWhl2];
            data = readline(client);
            wn = sscanf(data,"SC[0].B[0].wn = %e %e %e");
            data = readline(client);
            qbn=sscanf(data,"SC[0].B[0].qn = %e %e %e %e");            
            qbn_aux = qbn(4);
            qbn(2:4)=qbn(1:3);
            qbn(1)=qbn_aux;
            (readline(client));
            (readline(client));
            (readline(client));
            data = readline(client);
            PosN = sscanf(data,"Orb[0].PosN = %e %e %e");
            data = readline(client);
            VelN = sscanf(data,"Orb[0].VelN = %e %e %e");
            data = readline(client);
%             disp(strcat("last line: ", data));
            if (strcmp(data,"[EOF]"))
                readline(client);
                write(client,"Ack\n");
            end  
        end
        
        function updatePlots(app, omega, i)
            rad2deg         = 180/pi;
            PointsToPlot    = 120;

            Axes_Axes       = findobj(app.ReceiverAppUIFigure,'Tag','Axes_Axes');
            plot_w1_measured         = findobj(Axes_Axes,'Tag','plot_w1_measured');
            plot_w2_measured         = findobj(Axes_Axes,'Tag','plot_w2_measured');
            plot_w3_measured         = findobj(Axes_Axes,'Tag','plot_w3_measured');

            
            items = size(plot_w1_measured.XData,2);
            if items<=PointsToPlot-1
                items = PointsToPlot;
                Axes_Axes.XLim         = [0 120];
            else
                Axes_Axes.XLim         = [plot_w1_measured.XData(items-PointsToPlot+1) plot_w1_measured.XData(end)+1];
            end

            plot_w1_measured.XData = [plot_w1_measured.XData(items-PointsToPlot+1:end) plot_w1_measured.XData(end)+1];
            plot_w1_measured.YData = [plot_w1_measured.YData(items-PointsToPlot+1:end) omega(1)];
            plot_w2_measured.XData = [plot_w2_measured.XData(items-PointsToPlot+1:end) plot_w2_measured.XData(end)+1];
            plot_w2_measured.YData = [plot_w2_measured.YData(items-PointsToPlot+1:end) omega(2)];
            plot_w3_measured.XData = [plot_w3_measured.XData(items-PointsToPlot+1:end) plot_w3_measured.XData(end)+1];
            plot_w3_measured.YData = [plot_w3_measured.YData(items-PointsToPlot+1:end) omega(3)];
           
            pause(0.05)
        end
        
        function writeCommand(app, client, TcmdWhl, m)
            writeline(client, " ");
            writeline(client, strcat("SC[0].AC.Whl[0].Tcmd = ", num2str(TcmdWhl(1))));
            writeline(client, strcat("SC[0].AC.Whl[1].Tcmd = ", num2str(TcmdWhl(2))));
            writeline(client, strcat("SC[0].AC.Whl[2].Tcmd = ", num2str(TcmdWhl(3))));
            writeline(client, strcat("SC[0].AC.MTB[0].Mcmd = ", num2str(m(1))));
            writeline(client, strcat("SC[0].AC.MTB[1].Mcmd = ", num2str(m(2))));
            writeline(client, strcat("SC[0].AC.MTB[2].Mcmd = ", num2str(m(3))));
            writeline(client,"[EOF]");
            readline(client);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            initializeAxes(app);
            exitFlag =1;
            i = 0;
            client = tcpclient("localhost",10001);
            auxiliar=[0 0 0 0 0 0 0 0 0 0];
            while (exitFlag)
                i = i+1;
                % Read operation
                [current_time, svb, bvb, wn, qbn, HWhl, PosN, VelN] = read_data(app, client);
                app.current_time = [app.current_time ;current_time];
                app.svb = [app.svb ;svb'];
                app.bvb = [app.bvb ;bvb'];
                app.wn = [app.wn ;wn'];
                app.qbn = [app.qbn ;qbn'];
                app.HWhl = [app.HWhl ;HWhl];
                app.PosN = [app.PosN ;PosN'];
                app.VelN = [app.VelN ;VelN'];
                [TcmdWhl, m, auxiliar] = ADCS_function(current_time, svb, bvb, wn, qbn, HWhl, PosN, VelN, auxiliar);
                app.m = [app.m; m];
                app.TcmdWhl = [app.TcmdWhl; TcmdWhl];
                writeCommand(app, client, TcmdWhl, m);
                updatePlots(app, wn, i);
            end
        end

        % Close request function: ReceiverAppUIFigure
        function ReceiverAppUIFigureCloseRequest(app, event)
            output.current_time = app.current_time;
            output.svb = app.svb;
            output.bvb = app.bvb;
            output.wn = app.wn;
            output.qbn = app.qbn;
            output.HWhl = app.HWhl;
            output.PosN = app.PosN;
            output.VelN = app.VelN;
            output.TcmdWhl = app.TcmdWhl;
            output.m = app.m;         
            save('ADCS_Output.mat','output');
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReceiverAppUIFigure = uifigure('Visible', 'off');
            app.ReceiverAppUIFigure.Name = 'Receiver App';
            app.ReceiverAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReceiverAppUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ReceiverAppUIFigure);
            title(app.UIAxes, 'Angular velocity (rad/s)')
            app.UIAxes.Tag = 'Axes_Axes';
            app.StartButton = uibutton(app.ReceiverAppUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.ReceiverAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReceiverAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReceiverAppUIFigure)
        end
    end
end